classdef traj_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        spacedynamicButton     matlab.ui.control.Button
        payloadEditField       matlab.ui.control.NumericEditField
        payloadEditFieldLabel  matlab.ui.control.Label
        dynamicButton          matlab.ui.control.Button
        ctrajButton            matlab.ui.control.Button
        jtrajButton            matlab.ui.control.Button
        STARTButton            matlab.ui.control.StateButton
        Switch                 matlab.ui.control.Switch
        SwitchLabel            matlab.ui.control.Label
        Slider_6               matlab.ui.control.Slider
        Slider_6Label          matlab.ui.control.Label
        Slider_5               matlab.ui.control.Slider
        Slider_5Label          matlab.ui.control.Label
        Slider_4               matlab.ui.control.Slider
        Slider_4Label          matlab.ui.control.Label
        Slider_3               matlab.ui.control.Slider
        Slider_3Label          matlab.ui.control.Label
        Slider_2               matlab.ui.control.Slider
        Slider_2Label          matlab.ui.control.Label
        Slider                 matlab.ui.control.Slider
        SliderLabel            matlab.ui.control.Label
    end

    
    properties (Access = private)
        robot % Description
        payload % Description
    end
    
    methods (Access = private)
        
        function results = module_show(app)
            %% 模型導入
            clc;
            %%%%%%%%%%%%%%%%%%%%%%%%
            %% 建立機器人模型
            deg = pi/180;

            % 'm'
            mass = [3.7000, 8.3930, 2.33, 1.2190, 1.2190, 0.1897];
            
            % 'r'
            center_of_mass = [
                  0,-0.02561, 0.00193
                  0.2125, 0, 0.11336
                  0.15, 0, 0.0265
                  0, -0.0018, 0.01634
                  0, -0.0018, 0.01634
                  0, 0, -0.001159];
            % I => ixx , iyy , izz 
            inertial = [
                  0.010267495893, 0.010267495893, 0.00666
                  0.22689067591, 0.22689067591, 0.0151074
                  0.049443313556, 0.049443313556, 0.004095
                  0.111172755531, 0.111172755531, 0.21942
                  0.111172755531, 0.111172755531, 0.21942
                  0.0171364731454, 0.0171364731454, 0.033822
            ];
            % robot length values (metres)
            % theta    d        a        alpha     offset
            L(1)=Link([0       0.08916    0        pi/2      0     ],'standard'); 
            L(1).m = 3.7000; %質心
            L(1).r = [0,-0.02561, 0.00193]; % 鏈接 COG wrt 鏈接坐標系 3x1
            L(1).I = [0.010267495893, 0.010267495893, 0.00666, 0, 0, 0];% 鏈接慣性矩陣，對稱 3x3，關於鏈接 COG。
            L(1).G = 100; %齒輪比
            L(1).Jm = 0.0; % 執行器：電機慣量（電機參考） 
            
            L(2)=Link([0       0        0.425     0         0     ],'standard');
            L(2).m = 8.3930;
            L(2).r = [0.2125, 0, 0.11336];
            L(2).I = [0.22689067591, 0.22689067591, 0.0151074, 0, 0, 0];
            L(2).G = 100;
            L(2).Jm = 0.0;
            
            L(3)=Link([0       0        0.39225   0         0     ],'standard');
            L(3).m = 2.33;
            L(3).r = [0.15, 0, 0.0265];
            L(3).I = [0.049443313556, 0.049443313556, 0.004095, 0, 0, 0];
            L(3).G = 100;
            L(3).Jm = 0.0;
            
            L(4)=Link([0       0.10915   0        pi/2      0     ],'standard');
            L(4).m = 1.2190;
            L(4).r = [0, -0.0018, 0.01634];
            L(4).I = [0.111172755531, 0.111172755531, 0.21942, 0, 0, 0];
            L(4).G = 100;
            L(4).Jm = 0.0;
            
            L(5)=Link([0       0.09456    0        -pi/2     0     ],'standard');
            L(5).m = 1.2190;
            L(5).r = [0, -0.0018, 0.01634];
            L(5).I = [0.111172755531, 0.111172755531, 0.21942, 0, 0, 0];
            L(5).G = 100;
            L(5).Jm = 0.0;
            
            L(6)=Link([0       0.0823     0        0         0     ],'standard');
            L(6).m = 0.1897;
            L(6).r = [0, 0, -0.001159];
            L(6).I = [0.0171364731454, 0.0171364731454, 0.033822, 0, 0, 0];
            L(6).G = 100; 
            L(6).Jm = 0.0;
            
            % and build a serial link manipulator
            % offsets from the table on page 4, "Mico" angles are the passed joint
            % angles.  "DH Algo" are the result after adding the joint angle offset.
            app.robot = SerialLink(L, ...
                  'name', 'TECO ARM', 'manufacturer', 'Universal Robotics');
            
            % add the mass data, no inertia available
            % links = robot.links;
            % for i=1:6
            %    links(i).m = mass(i);
            %    links(i).r = center_of_mass(i,:);
            %    links(i).I = inertial(i,:);
            % end
            nargin = 0
            % place the variables into the global workspace
            if nargin == 1
               r = app.robot;
            elseif nargin == 0
               assignin('caller', 'ur5', app.robot);
               assignin('caller', 'qz', [0 0 0 0 0 0]); % zero angles
               assignin('caller', 'qr', [180 0 0 0 90 0]*deg); % vertical pose as per Fig 2
            end
            
            app.robot.plot([0,0,0,0,0,0]);%輸出機器人模型，後面的六個角為輸出時的theta姿態
            app.robot.display();
            teach(app.robot);
            
            % 对于空中机械臂，重力与坐标系方向一致，所以为正
            % 这与matlab自带的重力系统相反，所以matlab自带函数为负
            % 重力单位是m·s-2，也是N/kg,考虑到第二种意义，这里不改变数量值
            % robot.gravity=[0;0;-9.81];
            
            app.robot.plot([0,0,0,0,0,0]);%輸出機器人模型，後面的六個角為輸出時的theta姿態
            app.robot.display();
            teach(app.robot);
            theta=[0,0,0,0,0,0];%指定關節角
            p=app.robot.fkine(theta)%fkine正解函數，根據我們給定的關節角theta，求解出末端位置p
            q=app.robot.ikine(p)%ikine逆解函數，根據我們給定的末端位置p，求解出關節角q
        end
        
        function results = jtraj(app)
            %******************%
            %      jtraj       %
            %******************%
            %% 角度 to 角度
            init_ang = [0 0 0 0 0 0];
            targ_ang = [pi/4, -pi/3, pi/5, pi/2, -pi/4, pi/6];
            step = 50;
            [q,qd,qdd] = jtraj(init_ang,targ_ang,step);                                %直接得到角度、角速度、角加速度的的序列
            %% 位置 to 位置
            % T1=transl(0.5,0,0);%根據給定始始點，得到起始點位置
            % T2=transl(0,0.5,0.5);%根據給定終止點，得到終止點位置
            % q1=robot.ikine(T1);%根據起始點位置，得到起始點關節角
            % q2=robot.ikine(T2);%根據終止點位置，得到終止點關節角
            % [q ,qd, qdd]=jtraj(q1,q2,50); %五次多項式軌跡，得到關節角度，角速度，角加速度，50為採樣數
        
            grid on
            T=app.robot.fkine(q);%根据插值，得到末端執行器的位置
            nT=T.T; plot3(squeeze(nT(1,4,:)),squeeze(nT(2,4,:)),squeeze(nT(3,4,:)));%輸出末端點軌跡
        
            zlim([-1.5 1.5])
            hold on
            app.robot.plot(q);%動畫展示
        end
        
        function results = ctraj(app)
            %******************%
            %      ctraj       %
            %******************%
            %參數設置
            init_ang = [0 0 0 0 0 0];
            targ_ang = [pi/4, -pi/3, pi/5, pi/2, -pi/4, pi/6];
            step = 50;
        
            T0 = app.robot.fkine(init_ang)
            T1 = app.robot.fkine(targ_ang)
        
            Tc = ctraj(T0,T1,step);                                                    %得到每一步的T陣列
            q = app.robot.ikine(Tc,'mask',[1 1 1 1 0 1]);
            grid on
            nT=Tc.T;
            plot3(squeeze(nT(1,4,:)),squeeze(nT(2,4,:)),squeeze(nT(3,4,:)));%輸出末端點軌跡
        
            zlim([-1.5 1.5])
            hold on
            app.robot.plot(q);%動畫展示
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
%             traj = value;
%             if (value == 1)
%                 traj = 'jtraj'
%             
%             elseif ( value == 0)
%                 traj = 'ctraj'
%             end
             switch lower(value)
                case 'off'
                    app.jtraj();
                case 'on'
                    app.ctraj();
            end
        end

        % Value changed function: STARTButton
        function STARTButtonValueChanged(app, event)
            value = app.STARTButton.Value;
            app.module_show();
        end

        % Button pushed function: ctrajButton
        function ctrajButtonPushed(app, event)
            app.ctraj();
        end

        % Button pushed function: jtrajButton
        function jtrajButtonPushed(app, event)
            app.jtraj();
        end

        % Button pushed function: dynamicButton
        function dynamicButtonPushed(app, event)
            du=pi/180;
            ra=180/pi;
            q=[0,30,0,0,0,0];
            app.robot.gravity=[0;0;9.81];
            app.robot.payload(app.payload, [0 0 0])
            
            %% 角度 to 角度
            init_ang = [0 0 0 0 0 0];
            targ_ang = [0, pi/6, 0, 0, 0, 0];
            step = 50;
            [q,qd,qdd] = jtraj(init_ang,targ_ang,step);                                %直接得到角度、角速度、角加速度的的序列
            
            grid on
            T=app.robot.fkine(q);%根據插值，得到末端執行器的位置
            nT=T.T; plot3(squeeze(nT(1,4,:)),squeeze(nT(2,4,:)),squeeze(nT(3,4,:)));%輸出末端點軌跡
            % squeeze函數用於刪除矩陣中的單一維（Remove singleton dimensions）
            torque = app.robot.gravload(q)
            
            zlim([-1.5 1.5])
            hold on
            app.robot.plot(q);%動畫展示
        end

        % Value changed function: payloadEditField
        function payloadEditFieldValueChanged(app, event)
            value = app.payloadEditField.Value;
            app.payload = value;
        end

        % Button pushed function: spacedynamicButton
        function spacedynamicButtonPushed(app, event)
            %六軸機械臂工作空間計算，即最大payload
            format short;%保留精度
            app.robot.gravity=[0;0;9.81];
            app.robot.payload(5, [0 0 0]) % payload 5kg
            %% 
            %角度轉換
            du=pi/180;  %度
            radian=180/pi; %弧度
            %% 數值法 求取工作空間
            %關節角限位
            q1_s=-180   ;q1_end=180;
            q2_s=-90    ;q2_end=90;
            q3_s=-90    ;q3_end=90;
            q4_s=-180   ;q4_end=180;
            q5_s=-90    ;q5_end=90;
            q6_s=0      ;q6_end=360;
            
            %計算參數
            step=20;%計算步距 % 解析度
            %t=0:1:(q5_end-q5_s)/step;%產生時間向量 
            step1 = (q1_end - q1_s)/step; 
            step2 = (q2_end - q2_s)/step; 
            step3 = (q3_end - q3_s)/step; 
            step4 = (q4_end - q4_s)/step; 
            step5 = (q5_end - q5_s)/step; 
            step6 = (q6_end - q6_s)/step; 
            %% T_cell={((q1_end-q1_s)/step)*((q2_end-q2_s)/step)*((q3_end-q3_s)/step)*((q4_end-q4_s)/step)};
            
            %窮舉法正運動學計算工作空間
            tic;%tic1    
            i = 1;
            T = zeros(3,1);
            T_x = zeros(1,step1*step2*step3*step4*step5);
            T_y = zeros(1,step1*step2*step3*step4*step5);
            T_z = zeros(1,step1*step2*step3*step4*step5);
            for  q1=q1_s:step:q1_end
                for  q2=q2_s:step:q2_end
                    for  q3=q3_s:step:q3_end
                        for  q4=q4_s:step:q4_end
                            for  q5=q5_s:step:q5_end
                                T = app.robot.fkine([q1*du q2*du q3*du q4*du q5*du 0*du])
                                torque = app.robot.gravload([q1*du q2*du q3*du q4*du q5*du 0*du])
                                % A = T.t(1)
                                T_x(1,i) = T.t(1);
                                T_y(1,i) = T.t(2);
                                T_z(1,i) = T.t(3);
                                i=i+1;
                            end
                        end
                    end
                end 
            end
            disp(['循環運行時間：',num2str(toc)]); 
            t1=clock;
            
            %繪製工作空間
            figure('name','六軸機械臂工作空間')
            hold on
            plotopt = {'noraise', 'nowrist', 'nojaxes', 'delay',0};
            app.robot.plot([0 0 0 0 0 0], plotopt{:});
            plot3(T_x,T_y,T_z,'r.','MarkerSize',3);
            disp(['繪製工作空間運行時間：',num2str(etime(clock,t1))]);  
            
            %獲取X,Y,Z空間座標範圍,及圓範圍用於判讀座標可靠性
            X_min=min(T_x);
            Y_min=min(T_y);
            Z_min=min(T_z);
            X_max=max(T_x);
            Y_max=max(T_y);
            Z_max=max(T_z);
            R_min_squre=X_min^2+Y_min^2+Z_min^2;
            R_max_squre=X_max^2+Y_max^2+Z_max^2;
            Point_range=[X_min X_max Y_min Y_max Z_min Z_max R_max_squre]
            hold off
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider_2Label = uilabel(app.UIFigure);
            app.Slider_2Label.Text = 'Slider';
            app.Slider_2 = uislider(app.UIFigure);
            app.Slider_3Label = uilabel(app.UIFigure);
            app.Slider_3Label.Text = 'Slider';
            app.Slider_3 = uislider(app.UIFigure);
            app.Slider_4Label = uilabel(app.UIFigure);
            app.Slider_4Label.Text = 'Slider';
            app.Slider_4 = uislider(app.UIFigure);
            app.Slider_5Label = uilabel(app.UIFigure);
            app.Slider_5Label.Text = 'Slider';
            app.Slider_5 = uislider(app.UIFigure);
            app.Slider_6Label = uilabel(app.UIFigure);
            app.Slider_6Label.Text = 'Slider';
            app.Slider_6 = uislider(app.UIFigure);
            app.SwitchLabel = uilabel(app.UIFigure);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.STARTButton = uibutton(app.UIFigure, 'state');
            app.STARTButton.ValueChangedFcn = createCallbackFcn(app, @STARTButtonValueChanged, true);
            app.STARTButton.Text = 'START';
            app.jtrajButton = uibutton(app.UIFigure, 'push');
            app.jtrajButton.ButtonPushedFcn = createCallbackFcn(app, @jtrajButtonPushed, true);
            app.jtrajButton.Text = 'jtraj';
            app.ctrajButton = uibutton(app.UIFigure, 'push');
            app.ctrajButton.ButtonPushedFcn = createCallbackFcn(app, @ctrajButtonPushed, true);
            app.ctrajButton.Text = 'ctraj';
            app.dynamicButton = uibutton(app.UIFigure, 'push');
            app.dynamicButton.ButtonPushedFcn = createCallbackFcn(app, @dynamicButtonPushed, true);
            app.dynamicButton.Text = 'dynamic';
            app.payloadEditFieldLabel = uilabel(app.UIFigure);
            app.payloadEditFieldLabel.Text = 'payload';
            app.payloadEditField = uieditfield(app.UIFigure, 'numeric');
            app.payloadEditField.ValueChangedFcn = createCallbackFcn(app, @payloadEditFieldValueChanged, true);
            app.spacedynamicButton = uibutton(app.UIFigure, 'push');
            app.spacedynamicButton.ButtonPushedFcn = createCallbackFcn(app, @spacedynamicButtonPushed, true);
            app.spacedynamicButton.Text = 'space dynamic';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = traj_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end